classdef myapp1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        DeleteStringButton  matlab.ui.control.Button
        ListBox             matlab.ui.control.ListBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DeleteStringButton
        function DeleteStringButtonPushed(app, event)
            % 获取当前所有项
            items = app.ListBox.Items;
            if isempty(items)
                return;
            end
            
            selectedItem = app.ListBox.Value;
            selectedIndex = find(strcmp(items,selectedItem));

            if ~isempty(items) && ~isempty(selectedIndex)
                items(selectedIndex) = [];
                app.ListBox.Items = items;
                if ~isempty(items)
                    if selectedIndex > 1
                    app.ListBox.Value = items{selectedIndex - 1};
                    else
                    app.ListBox.Value = items{1};
                    end
                else
                    app.ListBox.Value = {};
                end
            end
 
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'One', 'Two', 'Three', 'Four'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'One';
            app.DeleteStringButton = uibutton(app.UIFigure, 'push');
            app.DeleteStringButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteStringButtonPushed, true);
            app.DeleteStringButton.Text = 'Delete String';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = myapp1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end