classdef myapp33 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        EditField_3           matlab.ui.control.EditField
        Label_2               matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        CalculateButton       matlab.ui.control.Button
        DropDown              matlab.ui.control.DropDown
        EditField_2           matlab.ui.control.NumericEditField
        EditField_1           matlab.ui.control.NumericEditField
        ContextMenu           matlab.ui.container.ContextMenu
        ExportDatatoBaseMenu  matlab.ui.container.Menu
    end

    
    properties (Access = private)
        OperationHistory = {};
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            num1 = app.EditField_1.Value;
            num2 = app.EditField_2.Value;

            operation = app.DropDown.Value;

            switch operation
                case 'Add'
                    result = num1 + num2;
                    operationSymbol = '+';
                case 'Multiply'
                    result = num1 * num2;
                    operationSymbol = 'x';
                case 'Subtract'
                     result = num1 - num2;
                     operationSymbol = '-';
                case 'Divide'
                    if num2 ~= 0
                        result = round(num1 / num2, 4);

                    else
                        uialert(app.UIFigure, '除数不能为零', 'Error');
                        return;
                    end
                    operationSymbol = '/';
                case 'Power'
                    result = num1 ^ num2;
                    operationSymbol = '^';
            end

            app.EditField_3.Value = num2str(result);
            operationStr = sprintf('%2.2f %s %2.2f = %2.2f', num1, operationSymbol, num2, result);
            app.OperationHistory{end+1, 1} = operationStr;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)

            operation = app.DropDown.Value;
            operationSymbol = '';

            % 根据下拉菜单的值执行操作
            switch operation
                case 'Add'
                    operationSymbol = '+';

                case 'Multiply'
                    operationSymbol = 'x';

                case 'Subtract'
                    operationSymbol = '-';

                case 'Divide'
                    operationSymbol = '/';

                case 'Power'
                    operationSymbol = '^';

            end
            % 这个函数修改的是运算符号
            app.Label.Text = operationSymbol;
        end

        % Menu selected function: ExportDatatoBaseMenu
        function ExportDatatoBaseMenuSelected(app, event)
  
                prompt = {'输入变量名：'};
                title = '导出历史记录';
                dims = [1 35];
                definput = {'MyData'};
                varName = inputdlg(prompt, title, dims, definput);
                
                if ~isempty(varName) && isvarname(varName{1})
                    assignin('base', varName{1}, app.OperationHistory);
                else
                    uialert(app.UIFigure, '请输入有效的变量名', '错误');
                end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_1.Value = 3;
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Value = 2;
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Add', 'Multiply', 'Subtract', 'Divide', 'Power'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Add';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '+';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '=';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.Value = 'Answer';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ExportDatatoBaseMenu = uimenu(app.ContextMenu);
            app.ExportDatatoBaseMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportDatatoBaseMenuSelected, true);
            app.ExportDatatoBaseMenu.Text = 'Export Data to Base';
            % Assign app.ContextMenu
            app.CalculateButton.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = myapp33

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end