classdef myapp7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        CounterLabel  matlab.ui.control.Label
        DropDown      matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        Counter = [0,0];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            selectedIndex = app.DropDown.Value;
            if strcmp(selectedIndex, 'Option 1')
                app.Counter(1) = app.Counter(1) + 1;
                
            elseif strcmp(selectedIndex, 'Option 2')
                app.Counter(2) = app.Counter(2) + 1;
            end
             app.CounterLabel.Text = sprintf('OPTION 1:  %i     OPTION 2:  %i', app.Counter(1), app.Counter(2));
             app.DropDown.Value = '';
             

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Option 1', 'Option 2', ''};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.CounterLabel = uilabel(app.UIFigure);
            app.CounterLabel.Text = 'OPTION 1:  0   OPTION 2:  0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = myapp7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end