classdef SUSSCAN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        LeftPanel        matlab.ui.container.Panel
        Panel_3          matlab.ui.container.Panel
        Button_4         matlab.ui.control.Button
        Button_3         matlab.ui.control.Button
        Button_2         matlab.ui.control.Button
        Button           matlab.ui.control.Button
        TabGroup         matlab.ui.container.TabGroup
        Tab              matlab.ui.container.Tab
        Button_15        matlab.ui.control.Button
        Button_14        matlab.ui.control.Button
        DropDown_2       matlab.ui.control.DropDown
        Label_2          matlab.ui.control.Label
        DropDown         matlab.ui.control.DropDown
        Label            matlab.ui.control.Label
        Tab_2            matlab.ui.container.Tab
        DropDown_5       matlab.ui.control.DropDown
        Label_4          matlab.ui.control.Label
        DropDown_4       matlab.ui.control.DropDown
        DropDown_4Label  matlab.ui.control.Label
        Button_13        matlab.ui.control.Button
        Button_10        matlab.ui.control.Button
        Button_9         matlab.ui.control.Button
        Button_8         matlab.ui.control.Button
        Button_7         matlab.ui.control.Button
        Tab_3            matlab.ui.container.Tab
        Button_12        matlab.ui.control.Button
        Button_11        matlab.ui.control.Button
        TextArea         matlab.ui.control.TextArea
        OCRButton        matlab.ui.control.Button
        RightPanel       matlab.ui.container.Panel
        UIAxes_2         matlab.ui.control.UIAxes
        UIAxes           matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        img; 
        img_process;
        img_scan;
        filter;
        edge_way;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % 打开图片
            [filename,pathname] = uigetfile({'*.jpg';'*.png';'*.*'},"选择图像");
            if isequal(filename,0) || isequal(pathname,0)
                errordlg("没有选中文件","错误");
            else
                file = strcat(pathname,filename);
            end
            app.img = imread(file);
            imshow(app.img, 'Parent', app.UIAxes);
            app.img_process = app.img;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % 保存图片至当前文件夹
            if isempty(app.img_process)
                uialert(app.UIFigure, '没有图片可保存！', '错误', 'Icon', 'error');
                return;
            end
            currentPath = pwd; 
            fileName = fullfile(currentPath, 'saved_image.png'); 
            imwrite(app.img_process, fileName);
            uialert(app.UIFigure, ['图片已保存至: ', fileName], '保存成功');
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            % 将图片另存为
             if isempty(app.img_process)
                uialert(app.UIFigure, '没有图片可保存！', '错误', 'Icon', 'error');
                return;
            end
            [file, path] = uiputfile({'*.png;*.jpg;*.bmp', 'Image Files (*.png, *.jpg, *.bmp)'}, ...
                             '另存为');
            if isequal(file, 0)
                return;
            end
        
            savePath = fullfile(path, file);
        
            imwrite(app.img, savePath);
            uialert(app.UIFigure, ['图片已保存至: ', savePath], '保存成功');
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            % 清除图窗
            cla(app.UIAxes, 'reset'); 
            cla(app.UIAxes_2, 'reset'); 
        

            app.img = [];
            app.img_process = [];
        
            title(app.UIAxes, '原始图像'); 
            title(app.UIAxes_2, '效果预览');
            xlabel(app.UIAxes, ''); ylabel(app.UIAxes, '');
            xlabel(app.UIAxes_2, ''); ylabel(app.UIAxes_2, '');

            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            % 翻转
            value = app.DropDown.Value;
            im = app.img_process;
            if value == '上下翻转'
                vertical = flip(im,1);
                imshow(vertical,'Parent',app.UIAxes_2);
                app.img_process = vertical;
            else 
                horizontal = flip(im,2);
                imshow(horizontal,'Parent',app.UIAxes_2);
                app.img_process = horizontal;
            end
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            % 缩放
            value = app.DropDown_2.Value;
           
            if isempty(app.img_process) 
                im = app.img;
            else 
                im = app.img_process;
            end
            [width1,height1] = size(im);
            if strcmp(value, '缩小25%')
                S = imresize(im, 0.25, 'nearest'); 
            elseif strcmp(value, '缩小50%')
                S = imresize(im, 0.5, 'nearest');  
            elseif strcmp(value, '放大2倍')
                S = imresize(im, 2, 'nearest');    
            else
                S = imresize(im, 4, 'nearest');    
            end
            [width2,height2] = size(S);
            message = sprintf('原始图像尺寸: %d × %d\n新图像尺寸: %d × %d', height1, width1, height2, width2);
            axis(app.UIAxes_2, 'image'); 
            app.UIAxes_2.XLim = [0 size(S, 2)]; 
            app.UIAxes_2.YLim = [0 size(S, 1)]; 
            imshow(S, 'Parent', app.UIAxes_2);
            uialert(app.UIFigure, message, '图像尺寸信息');
            app.img_process = S;
        end

        % Button pushed function: OCRButton
        function OCRButtonPushed(app, event)
            % OCR 文字提取
            im = app.img_scan;
            results = ocr(im);
            recognizedText = results.Text; 
            app.TextArea.Value = recognizedText;
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            % 保存提取到的文字在txt文件
            if isempty(app.TextArea.Value) || all(cellfun('isempty', app.TextArea.Value))
                uialert(app.UIFigure, '提取内容为空，无法保存！', '警告', 'Icon', 'warning');
                return;
            end
       
            [file, path] = uiputfile('*.txt', '保存提取文本为');
            
            if isequal(file, 0)
                return;
            end
        
            filePath = fullfile(path, file);
        
            try
                writelines(app.TextArea.Value, filePath);
                
            catch ME
                uialert(app.UIFigure, ['保存失败: ', ME.message], '错误', 'Icon', 'error');
            end
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            % 清除TextArea的内容
            app.TextArea.Value = '';
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            % 开始扫描
            if isempty(app.img_process) 
                im = app.img;
            else 
                im = app.img_process;
            end
            grayImg = rgb2gray(im);
            disp(app.filter);
            disp(app.edge_way);
            % 选择合适的滤波器
            if strcmp(app.filter, 'gaussian')
                filteredImg = imgaussfilt(grayImg, 2); % 高斯滤波
            elseif strcmp(app.filter, 'median')
                filteredImg = medfilt2(grayImg, [3 3]); % 中值滤波
            elseif strcmp(app.filter, 'average')
                kernel = fspecial('average', [3 3]); % 均值滤波
                filteredImg = imfilter(grayImg, kernel, 'replicate');
            else 
                filteredImg = imbilatfilt(grayImg);
            end
            
            enhancedImg = adapthisteq(filteredImg);

            % 选择合适的算子
            if strcmp(app.edge_way, 'Sobel')
                edges = edge(enhancedImg, 'Sobel');
            elseif strcmp(app.edge_way, 'Canny')
                edges = edge(enhancedImg, 'Canny'); 
            elseif strcmp(app.edge_way,  'Prewitt')
                edges = edge(enhancedImg, 'Prewitt');
            elseif strcmp(app.edge_way, 'Roberts')
                edges = edge(enhancedImg, 'Roberts');
            elseif strcmp(app.edge_way, 'LoG')
                edges = edge(enhancedImg, 'Log');
            else
                edges = edge(enhancedImg, 'zerocross');
            end

            [B, L] = bwboundaries(edges, 'noholes');
            stats = regionprops(L, 'BoundingBox', 'Area');
            [~, idx] = max([stats.Area]);
            boundingBox = stats(idx).BoundingBox;
           
            figureui = figure('Name', 'Select ROI', 'NumberTitle', 'off');
            imshow(im, []);
            title('请框选感兴趣区域');

            h = drawrectangle('Position', boundingBox, 'Color', 'g', 'LineWidth', 2);

            wait(h);
            
            adjustedPosition = h.Position; % [x, y, width, height]

            close(figureui);
            adjustedTopLeft = [adjustedPosition(1), adjustedPosition(2)];
            adjustedTopRight = [adjustedPosition(1) + adjustedPosition(3), adjustedPosition(2)];
            adjustedBottomLeft = [adjustedPosition(1), adjustedPosition(2) + adjustedPosition(4)];
            adjustedBottomRight = [adjustedPosition(1) + adjustedPosition(3), adjustedPosition(2) + adjustedPosition(4)];

            originalPoints = [adjustedTopLeft; adjustedTopRight;  
                              adjustedBottomRight;adjustedBottomLeft];

            aspectRatio = adjustedPosition(3) / adjustedPosition(4);

            targetHeight = 600; 
            targetWidth = round(targetHeight * aspectRatio); 
            
            targetPoints = [0 0; targetWidth 0; targetWidth targetHeight; 0 targetHeight];

            tform = fitgeotrans(originalPoints, targetPoints, 'projective');

            correctedImg = imwarp(im, tform, 'OutputView', imref2d([targetHeight, targetWidth]));

            mask = poly2mask(targetPoints(:,1), targetPoints(:,2), size(correctedImg, 1), size(correctedImg, 2));
            croppedImg = bsxfun(@times, correctedImg, cast(mask, 'like', correctedImg));
            app.img_process = croppedImg;
            imshow(croppedImg,'Parent',app.UIAxes_2);
           
        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
            % 滤波器
            value = app.DropDown_4.Value;
            if value == '高斯滤波'
                app.filter = 'gaussian';
            elseif value == '中值滤波'
                app.filter = 'median';
            elseif value == '均值滤波'
                app.filter = 'average';
            else
                app.filter = 'bilateral';
            end
        end

        % Value changed function: DropDown_5
        function DropDown_5ValueChanged(app, event)
            % 边缘检测方法
            value = app.DropDown_5.Value;
            if value == 'Sobel'
                app.edge_way = 'Sobel';
            elseif value == 'Canny'
                app.edge_way = 'Canny';
            elseif value == 'Prewitt'
                app.edge_way = 'Prewitt';
            elseif value == 'Roberts'
                app.edge_way = 'Roberts';
            elseif value == 'LoG'
                app.edge_way = 'LoG';
            else  
                app.edge_way = 'Zero Crossing';
            end
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            % 灰度化
            im = app.img_process;
            grayim = rgb2gray(im);
            imshow(grayim,'Parent',app.UIAxes_2);
            app.img_scan = grayim;
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            % 黑白  二值化
            im = app.img_process;
            binaryImg = imbinarize(rgb2gray(im), 'adaptive', 'ForegroundPolarity', 'dark', 'Sensitivity', 0.4);
            imshow(binaryImg,'Parent',app.UIAxes_2)
            app.img_scan = binaryImg;
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            % 增亮
            im = app.img_process;
            r = imadjust(im(:, :, 1), [], [], 1.2);
            g = imadjust(im(:, :, 2), [], [], 1.2); 
            b = imadjust(im(:, :, 3), [], [], 1.2);
            brightenedImg = cat(3, r, g, b);  
            imshow(brightenedImg,'Parent',app.UIAxes_2);
            app.img_scan = brightenedImg;
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            % 增强并锐化
            im = app.img_process;
            enhancedImg = imadjust(im, stretchlim(im), []);
            sharpenedImg = imsharpen(enhancedImg, 'Radius', 1, 'Amount', 1.5);
            imshow(sharpenedImg,'Parent',app.UIAxes_2);
            app.img_scan = sharpenedImg;
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            % 顺时针旋转
            if isempty(app.img_process) 
                im = app.img;
            else
                im = app.img_process;
            end
            rotate=imrotate(im,-90);
            imshow(rotate,'Parent',app.UIAxes_2);
            app.img_process = rotate;
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            % 逆时针旋转
            if isempty(app.img_process) 
                im = app.img;
            else
                im = app.img_process;
            end
            rotate=imrotate(im,90);
            imshow(rotate,'Parent',app.UIAxes_2);
            app.img_process = rotate;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {591, 591};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {257, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '基础';
            app.Label = uilabel(app.Tab);
            app.Label.Text = '翻转';
            app.DropDown = uidropdown(app.Tab);
            app.DropDown.Items = {'上下翻转', '左右翻转'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '上下翻转';
            app.Label_2 = uilabel(app.Tab);
            app.Label_2.Text = '缩放';
            app.DropDown_2 = uidropdown(app.Tab);
            app.DropDown_2.Items = {'缩小25%', '缩小50%', '放大2倍', '放大4倍'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = '缩小25%';
            app.Button_14 = uibutton(app.Tab, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '顺时针旋转';
            app.Button_15 = uibutton(app.Tab, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '逆时针旋转';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '扫描';
            app.Button_7 = uibutton(app.Tab_2, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '灰度';
            app.Button_8 = uibutton(app.Tab_2, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '黑白';
            app.Button_9 = uibutton(app.Tab_2, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '明亮';
            app.Button_10 = uibutton(app.Tab_2, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '增强并锐化';
            app.Button_13 = uibutton(app.Tab_2, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '开始扫描';
            app.DropDown_4Label = uilabel(app.Tab_2);
            app.DropDown_4Label.Text = '滤波器';
            app.DropDown_4 = uidropdown(app.Tab_2);
            app.DropDown_4.Items = {'高斯滤波', '中值滤波', '均值滤波', '双边滤波'};
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.DropDown_4.Value = '高斯滤波';
            app.Label_4 = uilabel(app.Tab_2);
            app.Label_4.Text = '边缘检测';
            app.DropDown_5 = uidropdown(app.Tab_2);
            app.DropDown_5.Items = {'Sobel', 'Canny', 'Prewitt', 'Roberts ', 'LoG', 'Zero Crossing'};
            app.DropDown_5.ValueChangedFcn = createCallbackFcn(app, @DropDown_5ValueChanged, true);
            app.DropDown_5.Value = 'Sobel';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '提取文字';
            app.OCRButton = uibutton(app.Tab_3, 'push');
            app.OCRButton.ButtonPushedFcn = createCallbackFcn(app, @OCRButtonPushed, true);
            app.OCRButton.Text = 'OCR';
            app.TextArea = uitextarea(app.Tab_3);
            app.Button_11 = uibutton(app.Tab_3, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '保存';
            app.Button_12 = uibutton(app.Tab_3, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '清除';
            app.Panel_3 = uipanel(app.LeftPanel);
            app.Panel_3.Title = '文件管理';
            app.Button = uibutton(app.Panel_3, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '打开图片';
            app.Button_2 = uibutton(app.Panel_3, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '保存图片';
            app.Button_3 = uibutton(app.Panel_3, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '另存为';
            app.Button_4 = uibutton(app.Panel_3, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '清除';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '原图')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, '效果预览')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SUSSCAN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end